classdef GUI_app_phase5 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        LinklengthsLabel                matlab.ui.control.Label
        GainsforclosedloopcontrolLabel  matlab.ui.control.Label
        SimulationtimeLabel             matlab.ui.control.Label
        DesiredEllipsegeometryLabel     matlab.ui.control.Label
        ClosedloopTaskspacecontrolLabel  matlab.ui.control.Label
        ycenterEditField                matlab.ui.control.NumericEditField
        ycenterEditFieldLabel           matlab.ui.control.Label
        xcenterEditField                matlab.ui.control.NumericEditField
        xcenterEditFieldLabel           matlab.ui.control.Label
        L1Slider                        matlab.ui.control.Slider
        L1SliderLabel                   matlab.ui.control.Label
        k2EditField                     matlab.ui.control.NumericEditField
        k2EditFieldLabel                matlab.ui.control.Label
        K1EditField                     matlab.ui.control.NumericEditField
        K1EditFieldLabel                matlab.ui.control.Label
        noofcyclesEditField             matlab.ui.control.NumericEditField
        noofcyclesEditFieldLabel        matlab.ui.control.Label
        timepercycleEditField           matlab.ui.control.NumericEditField
        timepercycleEditFieldLabel      matlab.ui.control.Label
        tiltdegEditFieldLabel           matlab.ui.control.Label
        tiltdegEditField                matlab.ui.control.NumericEditField
        L5Slider                        matlab.ui.control.Slider
        L5SliderLabel                   matlab.ui.control.Label
        L4Slider                        matlab.ui.control.Slider
        L4SliderLabel                   matlab.ui.control.Label
        L3Slider                        matlab.ui.control.Slider
        L2SliderLabel                   matlab.ui.control.Label
        L3SliderLabel                   matlab.ui.control.Label
        L2Slider                        matlab.ui.control.Slider
        aEditField                      matlab.ui.control.NumericEditField
        aEditFieldLabel                 matlab.ui.control.Label
        YinLabel                        matlab.ui.control.Label
        bEditField                      matlab.ui.control.NumericEditField
        LinkRRManipulatortracingEllipseLabel  matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        UIAxes2                         matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function results = func(app)
%% Open-loop controller function
function [theta_d_dot]= taskspacecontrol(t,theta,a,b,xc,yc,psi,L1,L2,L3,L4,L5,K1,K2,time_per_cycle)

L1=L1;
L2=L2;
L3=L3;
L4= L4;
L5=L5;
a= a;      % semi major axis
b= b;    % semi minor axis
xc=xc;      % Ellipse center
yc=yc;
psi=psi;    % Ellipse tilt angle
w=360/time_per_cycle;

K1 = K1; % Controller gain 1
K2 = K2 ; % Controller gain 2
K=diag([K1 K2]);

% Desired trajectory
Xd=[ xc ; yc] + [cosd(psi) -sind(psi) ;sind(psi) cosd(psi)]*[a*cosd(w*t); b*sind(w*t)];

% Desired X_dot
Xd_dot=[cosd(psi) -sind(psi) ;sind(psi) cosd(psi)]*[-a*w*sind(w*t); b*w*cosd(w*t)];

% All angles 0 to 360 deg
theta(1:5)=wrapTo360(theta(1:5));

% Forward Kinematics end effector co-ordinates
X(1) = L1.*cosd(theta(1)) + L2.*cosd(theta(2)) + L3.*cosd(theta(3)) + L4.*cosd(theta(4)) + L5.*cosd(theta(5));
X(2) = L1.*sind(theta(1)) + L2.*sind(theta(2)) + L3.*sind(theta(3)) + L4.*sind(theta(4)) + L5.*sind(theta(5));

% Jacobian
J = [-L1*sind(theta(1))  -L2*sind(theta(2))  -L3*sind(theta(3)) -L4*sind(theta(4)) -L5*sind(theta(5)); L1*cosd(theta(1))  L2*cosd(theta(2))  L3*cosd(theta(3))  L4*cosd(theta(4))  L5*cosd(theta(5))];

% All angles in 0 to 360 deg
theta(1:5)=wrapTo360(theta(1:5));

% Theta dot total
theta_d_dot= pinv(J)*(Xd_dot - K*[Xd(1)-X(1) ; Xd(2)-X(2)]);

end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function XaxisSliderValueChanged(app, event)
        
        end

        % Callback function: PlotButton, UIAxes2
        function PlotButtonPushed(app, event)
         
            % Co-ordinates
            a= app.aEditField.Value;
            b= app.bEditField.Value;
            xc= app.xcenterEditField.Value;
            yc= app.ycenterEditField.Value;
            psi= app.tiltdegEditField.Value;
            L1= app.L1Slider.Value;
            L2= app.L2Slider.Value;
            L3= app.L3Slider.Value;
            L4= app.L4Slider.Value;
            L5= app.L5Slider.Value;
            K1=app.K1EditField.Value;
            K2=app.k2EditField.Value;
            T=app.timepercycleEditField.Value;
            nc=app.noofcyclesEditField.Value;


phi = linspace(0, 360, 360);

%% X desired
X_desired = [ xc ; yc] + [cosd(psi) -sind(psi) ;sind(psi) cosd(psi)]*[a*cosd(phi) ; b*sind(phi)];

%% Simulation using ode45

theta0=[20;150;10;-30;-40];

% [time theta_sol]=ode45(@taskspacecontrol,[0,nc*T], theta0,a,b,xc,yc,psi,L1,L2,L3,L4,L5,K1,K2,T);

[time,theta_sol] = ode45(@(time,theta_sol) taskspacecontrol(time,theta_sol,a,b,xc,yc,psi,L1,L2,L3,L4,L5,K1,K2,T), [0,nc*T],  theta0);

% [t,y] = ode45(@(t,y) odefcn(t,y,A,B), tspan, y0);

theta_sol=theta_sol';

% Forward Kinematics end effector co-ordinates
X_actual = L1.*cosd(theta_sol(1,:)) + L2.*cosd(theta_sol(2,:)) + L3.*cosd(theta_sol(3,:)) + L4.*cosd(theta_sol(4,:)) + L5.*cosd(theta_sol(5,:));
Y_actual = L1.*sind(theta_sol(1,:)) + L2.*sind(theta_sol(2,:)) + L3.*sind(theta_sol(3,:)) + L4.*sind(theta_sol(4,:)) + L5.*sind(theta_sol(5,:));
            % time_step = [0:0.01:10]
            % time=[0 10]
            % 
            % [q2,qd2,qdd2,pp2] = quinticpolytraj([x1 x2; y1 y2],time,time_step);
            % 
            % 
            %   %% RR manipulator theta1 and theta2 
            %     a1=3;
            %     a2=2;
            % 
            %     for i=1:length(q2(1,:))
            % 
            %     [solutionq(i,1) solutionq(i,2)]=IK(q2(1,(i)),q2(2,(i)),a1,a2); %quintic traj
            % 
            %     %quintic
            %     theta1q(i)= solutionq(i,1);
            %     theta2q(i)=solutionq(i,2);
            % 
            %     end
            % 
            %      %% Inverse Kinematics function RR
            %     function [theta1, theta2]=IK(x,y,a1,a2)
            % 
            %     theta2= acosd(( x^2 + y^2 - (a1^2 + a2^2))/(2*a1*a2));
            % 
            %     theta2(imag(theta2)~=0)=NaN;
            %     theta1 = atan2d(y,x) - atan2d(a2*sind(theta2),a1+(a2*cosd(theta2)));
            % 
            %     if (theta1<=0) theta1 =theta1+360; end
            %     if (theta2<=0) theta2 =theta2+360; end
            % 
            %     end


            %% Open-loop controller function
function [theta_d_dot]= taskspacecontrol(t,theta,a,b,xc,yc,psi,L1,L2,L3,L4,L5,K1,K2,time_per_cycle)

L1=L1;
L2=L2;
L3=L3;
L4= L4;
L5=L5;
a= a;      % semi major axis
b= b;    % semi minor axis
xc=xc;      % Ellipse center
yc=yc;
psi=psi;    % Ellipse tilt angle
w=360/time_per_cycle;

K1 = K1; % Controller gain 1
K2 = K2 ; % Controller gain 2
K=diag([K1 K2]);

% Desired trajectory
Xd=[ xc ; yc] + [cosd(psi) -sind(psi) ;sind(psi) cosd(psi)]*[a*cosd(w*t); b*sind(w*t)];

% Desired X_dot
Xd_dot=[cosd(psi) -sind(psi) ;sind(psi) cosd(psi)]*[-a*w*sind(w*t); b*w*cosd(w*t)];

% All angles 0 to 360 deg
theta(1:5)=wrapTo360(theta(1:5));

% Forward Kinematics end effector co-ordinates
X(1) = L1.*cosd(theta(1)) + L2.*cosd(theta(2)) + L3.*cosd(theta(3)) + L4.*cosd(theta(4)) + L5.*cosd(theta(5));
X(2) = L1.*sind(theta(1)) + L2.*sind(theta(2)) + L3.*sind(theta(3)) + L4.*sind(theta(4)) + L5.*sind(theta(5));

% Jacobian
J = [-L1*sind(theta(1))  -L2*sind(theta(2))  -L3*sind(theta(3)) -L4*sind(theta(4)) -L5*sind(theta(5)); L1*cosd(theta(1))  L2*cosd(theta(2))  L3*cosd(theta(3))  L4*cosd(theta(4))  L5*cosd(theta(5))];

% All angles in 0 to 360 deg
theta(1:5)=wrapTo360(theta(1:5));

% Theta dot total
theta_d_dot= pinv(J)*(Xd_dot - K*[Xd(1)-X(1) ; Xd(2)-X(2)]);

end


%% Animation plot


for i=1:length(theta_sol)


x1= L1.*cosd(theta_sol(1,i));
x2= x1 + L2.*cosd(theta_sol(2,i));
x3= x2 + L3.*cosd(theta_sol(3,i));
x4= x3 + L4.*cosd(theta_sol(4,i));
x5= x4 + L5.*cosd(theta_sol(5,i));

y1= L1.*sind(theta_sol(1,i));
y2= y1 + L2.*sind(theta_sol(2,i));
y3= y2 + L3.*sind(theta_sol(3,i));
y4= y3 + L4.*sind(theta_sol(4,i));
y5= y4 + L5.*sind(theta_sol(5,i));

plot(app.UIAxes2,X_desired(1,:),X_desired(2,:),"LineWidth",1.5);
hold(app.UIAxes2,'on');

xtrace(i)=X_actual(i);
ytrace(i)=Y_actual(i);
plot(app.UIAxes2,xtrace ,ytrace,'r-',"LineWidth",0.1);

plot(app.UIAxes2,[0],[0],'k-^',"LineWidth",1.5)
plot(app.UIAxes2,[0 x1],[0 y1],'k-o',"LineWidth",1)
plot(app.UIAxes2,[x1 x2],[y1 y2],'k-o',"LineWidth",1)
plot(app.UIAxes2,[x2 x3],[y2 y3],'k-o',"LineWidth",1)
plot(app.UIAxes2,[x3 x4],[y3 y4],'k-o',"LineWidth",1)
plot(app.UIAxes2,[x4 x5],[y4 y5],'k-o',"LineWidth",1)
plot(app.UIAxes2,[x5],[y5],'r-x',"LineWidth",1.5)

legend(app.UIAxes2, 'Desired Trajectory', 'Actual Trajectory')
pause(0.1)
hold(app.UIAxes2,'off');
end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'X vs Y')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.XLim = [-5 5];
            app.UIAxes2.YLim = [-5 5];
            app.UIAxes2.XLimitMethod = 'tight';
            app.UIAxes2.YLimitMethod = 'tight';
            app.UIAxes2.ZLimitMethod = 'tight';
            app.UIAxes2.XTick = [-5 -4 -3 -2 -1 0 1 2 3 4 5];
            app.UIAxes2.LineStyleOrder = {'--'; '-'};
            app.UIAxes2.LineStyleCyclingMethod = 'withcolor';
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.LinkRRManipulatortracingEllipseLabel = uilabel(app.UIFigure);
            app.LinkRRManipulatortracingEllipseLabel.Text = '5 Link RR Manipulator tracing Ellipse';
            app.bEditField = uieditfield(app.UIFigure, 'numeric');
            app.YinLabel = uilabel(app.UIFigure);
            app.YinLabel.Text = 'b';
            app.aEditFieldLabel = uilabel(app.UIFigure);
            app.aEditFieldLabel.Text = 'a';
            app.aEditField = uieditfield(app.UIFigure, 'numeric');
            app.L2Slider = uislider(app.UIFigure);
            app.L2Slider.Limits = [0 3];
            app.L2Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3];
            app.L2Slider.MajorTickLabels = {'0', '0.5', '1', '1.5', '2', '2.5', '3'};
            app.L2Slider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.L2Slider.Value = 2.5;
            app.L3SliderLabel = uilabel(app.UIFigure);
            app.L3SliderLabel.Text = 'L3';
            app.L2SliderLabel = uilabel(app.UIFigure);
            app.L2SliderLabel.Text = 'L2';
            app.L3Slider = uislider(app.UIFigure);
            app.L3Slider.Limits = [0 3];
            app.L3Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3];
            app.L3Slider.MajorTickLabels = {'0', '0.5', '1', '1.5', '2', '2.5', '3'};
            app.L3Slider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.L3Slider.Value = 1;
            app.L4SliderLabel = uilabel(app.UIFigure);
            app.L4SliderLabel.Text = 'L4';
            app.L4Slider = uislider(app.UIFigure);
            app.L4Slider.Limits = [0 3];
            app.L4Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3];
            app.L4Slider.MajorTickLabels = {'0', '0.5', '1', '1.5', '2', '2.5', '3'};
            app.L4Slider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.L4Slider.Value = 1.5;
            app.L5SliderLabel = uilabel(app.UIFigure);
            app.L5SliderLabel.Text = 'L5';
            app.L5Slider = uislider(app.UIFigure);
            app.L5Slider.Limits = [0 3];
            app.L5Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3];
            app.L5Slider.MajorTickLabels = {'0', '0.5', '1', '1.5', '2', '2.5', '3'};
            app.L5Slider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.L5Slider.Value = 1;
            app.tiltdegEditField = uieditfield(app.UIFigure, 'numeric');
            app.tiltdegEditFieldLabel = uilabel(app.UIFigure);
            app.tiltdegEditFieldLabel.Text = 'tilt [deg]';
            app.timepercycleEditFieldLabel = uilabel(app.UIFigure);
            app.timepercycleEditFieldLabel.Text = 'time  per cycle';
            app.timepercycleEditField = uieditfield(app.UIFigure, 'numeric');
            app.noofcyclesEditFieldLabel = uilabel(app.UIFigure);
            app.noofcyclesEditFieldLabel.Text = 'no. of cycles';
            app.noofcyclesEditField = uieditfield(app.UIFigure, 'numeric');
            app.K1EditFieldLabel = uilabel(app.UIFigure);
            app.K1EditFieldLabel.Text = 'K1';
            app.K1EditField = uieditfield(app.UIFigure, 'numeric');
            app.k2EditFieldLabel = uilabel(app.UIFigure);
            app.k2EditFieldLabel.Text = 'k2';
            app.k2EditField = uieditfield(app.UIFigure, 'numeric');
            app.L1SliderLabel = uilabel(app.UIFigure);
            app.L1SliderLabel.Text = 'L1';
            app.L1Slider = uislider(app.UIFigure);
            app.L1Slider.Limits = [0 3];
            app.L1Slider.MajorTicks = [0 0.5 1 1.5 2 2.5 3];
            app.L1Slider.MajorTickLabels = {'0', '0.5', '1', '1.5', '2', '2.5', '3'};
            app.L1Slider.MinorTicks = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1 1.1 1.2 1.3 1.4 1.5 1.6 1.7 1.8 1.9 2 2.1 2.2 2.3 2.4 2.5 2.6 2.7 2.8 2.9 3];
            app.L1Slider.Value = 2;
            app.xcenterEditFieldLabel = uilabel(app.UIFigure);
            app.xcenterEditFieldLabel.Text = 'x center';
            app.xcenterEditField = uieditfield(app.UIFigure, 'numeric');
            app.ycenterEditFieldLabel = uilabel(app.UIFigure);
            app.ycenterEditFieldLabel.Text = 'y center';
            app.ycenterEditField = uieditfield(app.UIFigure, 'numeric');
            app.ClosedloopTaskspacecontrolLabel = uilabel(app.UIFigure);
            app.ClosedloopTaskspacecontrolLabel.Text = 'Closed-loop Task-space control';
            app.DesiredEllipsegeometryLabel = uilabel(app.UIFigure);
            app.DesiredEllipsegeometryLabel.Text = 'Desired Ellipse geometry';
            app.SimulationtimeLabel = uilabel(app.UIFigure);
            app.SimulationtimeLabel.Text = 'Simulation time';
            app.GainsforclosedloopcontrolLabel = uilabel(app.UIFigure);
            app.GainsforclosedloopcontrolLabel.Text = 'Gains for closed-loop control';
            app.LinklengthsLabel = uilabel(app.UIFigure);
            app.LinklengthsLabel.Text = 'Link lengths';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI_app_phase5

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end